classdef TickTock < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TICKTICKLabel  matlab.ui.control.Label
        GMT8Label_2    matlab.ui.control.Label
        GMT8EditField  matlab.ui.control.EditField
        Button         matlab.ui.control.Button
        Button_2       matlab.ui.control.StateButton
        Label          matlab.ui.control.Label
        Lamp           matlab.ui.control.Lamp
        UIAxes         matlab.ui.control.UIAxes
        SysInfoLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        CurTime string % 控件时间显示
    end
    
    properties (Access = private)
        % 时间显示定时器
        Timer_01 = timer('StartDelay', 5, 'Period', 1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        
        % 时间统计
        StartTick = '';   % 计时器启动时间
        EndTick = '';     % 计时器结束时间
        ItemDuration = 0; % 持续时间 min
        ItemName char ;   % 事件
        ItemDetail char;  % 事件详情
        
        
        % 定时器,刷新显示
        Timer_02 = timer('Period', 1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate'); % Description
    end
    
    methods (Access = private)
        
        function ShowTime_01(app)
            % 显示窗口二
            if true
                app.GMT8Label_2.FontName = 'Serif';
                app.GMT8Label_2.FontSize = 14;
                app.GMT8Label_2.FontWeight = 'bold';
                CurTime_02 = strcat(datestr(now,'yyyy/mm/dd'),'-',datestr(now,'hh:mm:ss'));
                app.GMT8Label_2.FontColor =  [0.26 0.26 0.26];
                app.GMT8EditField.Value = CurTime_02;
            end
        end
        
        function DrawDurationBar_01(app)
            temp = etime(datevec(datetime('now')),datevec(app.StartTick));
            app.ItemDuration = int32(temp/60); % min
            b_1 = barh(app.UIAxes,0.25,app.ItemDuration,0.45);
            % 设置柱状图
            b_1.FaceColor = [0 1 0];
            b_1.EdgeColor = [rand rand rand];
            b_1.LineWidth = 1.2;
        end
        
        %{
            Designe thoughts:
            1.Keep an item information by one line in log_01.txt,
            including item name,time,duration and so on;
        %}
        function KeepItemInfo(app)
            logpath = 'D:\Codes\MatlabFiles\Blocks\TickTockFiles';
            logname = 'log.txt';
            fileID = fopen(strcat(logpath,'\',logname),'a+');
            if ~isequal(fileID,0)
                % 时间|事项|详情|持续时间
%                 StTemp = datestr(app.StartTick,'yy/mm/dd hh:mm');
%                 EtTemp = datestr(app.EndTick,'yy/mm/dd hh:mm');
%                 fprintf(fileID,'%s %s|%s|%s|%d',StTemp,EtTemp,'fd','df',19);
                  disp('file open succeed...')
            end
            fclose(fileID);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            InfoStart = strcat('启动嘀嗒工具','');
            fprintf('%s\n',InfoStart);
            % 绘制背景图
            % app.UIFigure.ImageSource = 'Wallpaper_21.jpg';
            
            % 初始化窗口显示
            app.GMT8EditField.Value = "Initializing...";
            
            % 启动定时器1
            app.Timer_01.TimerFcn = @(~, ~) ShowTime_01(app);
            start(app.Timer_01);
            
            % 定时器2 绑定函数
            app.Timer_02.TimerFcn = @(~,~) DrawDurationBar_01(app);
            
            % 初始化时间
            app.StartTick = datestr(now);
            app.EndTick = datestr(now);
            % fprintf('%s,%s\n',app.StartTick,app.EndTick);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.Button_2.Value
                sysInfo = '记录按钮不可用';
                app.SysInfoLabel.Text = sysInfo;
            else
                sysInfo = '按下记录';
                app.SysInfoLabel.Text = sysInfo;
                KeepItemInfo(app);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer_01);
            delete(timerfind);
            delete(app);
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            % 初始化之后value = true;
            value = app.Button_2.Value;
            state_01 = ["启动计时","停止计时"];
            if ~value
                % 启动计时器状态中
                app.Button_2.Text = state_01(1);
                app.Lamp.Color = [0.64 0.08 0.1];
                
                app.ItemDuration = 0;
                app.EndTick = datestr(now);
                
                if ~isempty(timerfind(app.Timer_02))
                    stop(app.Timer_02);
                end
            else
                app.Button_2.Text = state_01(2);
                app.Lamp.Color = [0 1 0];
                
                % 停止计时器状态中
                app.StartTick = datestr(now);
                start(app.Timer_02);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TICKTICKLabel = uilabel(app.UIFigure);
            app.TICKTICKLabel.Text = 'TICK TICK';
            app.GMT8Label_2 = uilabel(app.UIFigure);
            app.GMT8Label_2.Text = 'GMT+8';
            app.GMT8EditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '记录';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Icon = 'power_button.png';
            app.Button_2.Text = '计时器';
            app.Button_2.Value = true;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 240];
            app.UIAxes.YLim = [0 0.5];
            app.UIAxes.XTick = [0 30 60 90 120 150 180 210 240];
            app.UIAxes.XTickLabel = {'0'; '30'; '60'; '90'; '120'; '150'; '180'; '210'; '240'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.SysInfoLabel = uilabel(app.UIFigure);
            app.SysInfoLabel.Text = 'SysInfo';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TickTock

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end